classdef GUIBiomecanica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        SwitchLabel  matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        Lamp         matlab.ui.control.Lamp
        axsPosicion  matlab.ui.control.UIAxes
        UITable      matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
           app.UITable.Data = zeros([25 2]);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global depthVid datos    
             
            value = app.Switch.Value;
            if strcmp(value,'On')
                close all;
                clc;
                app.Lamp.Color = [0.00,1.00,0.00];
                %puntos = [];
                imaqreset; %Resetea el objeto de imagenes
                
                %Video de entra, sensor de profundidad
                depthVid = videoinput('kinect',2);
                
                %obtiene las propiedades del video de entrada
                depthSource = getselectedsource(depthVid);
                depthSource.EnableBodyTracking = 'on'; %Enciende el seguimiendo del puntos
                
                %confuguracion del disparador
                triggerconfig(depthVid,'manual');
                depthVid.FramesPerTrigger = 1;
                depthVid.TriggerRepeat = inf;
                %set(getselectedsource(depthVid));
                
                %Inicio del video de profundidad
                start(depthVid);
                himg = figure; %crea un objeto figure
                
                while ishandle(himg)
                    trigger(depthVid);
                    [depthMap,~,depthMetaData] = getdata(depthVid);
                    imshow(depthMap,[0 4096]);
                    
                    %verifica si la suma del seguimiento del cuerpo es mayor a cero
                    if sum(depthMetaData.IsBodyTracked)>0
                        skeletonJoints = depthMetaData.DepthJointIndices(:,:,depthMetaData.IsBodyTracked);
                        hold on;
                        plot(skeletonJoints(:,1), skeletonJoints(:,2),'*');
                        hold off;
                        plot(app.axsPosicion,skeletonJoints(:,2),'*-');
                        %else
                        datos = [skeletonJoints(:,1) skeletonJoints(:,2)];
                        app.UITable.Data = datos;
                    end
                end                
                stop(depthVid);                
            else
                close all;
                app.Lamp.Color = [0.64,0.08,0.18];
                clearAllMemoizedCaches
            end%end else
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SwitchLabel = uilabel(app.Panel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.axsPosicion = uiaxes(app.UIFigure);
            title(app.axsPosicion, 'Posicion')
            xlabel(app.axsPosicion, 'X')
            ylabel(app.axsPosicion, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIBiomecanica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end